classdef appUIMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main                  matlab.ui.Figure
        Menu                  matlab.ui.container.Menu
        Menu2                 matlab.ui.container.Menu
        TabGroup              matlab.ui.container.TabGroup
        StreamingTab          matlab.ui.container.Tab
        DebugButton           matlab.ui.control.Button
        button_play           matlab.ui.control.Button
        IsstreamingLamp       matlab.ui.control.Lamp
        IsstreamingLampLabel  matlab.ui.control.Label
        StaticTab             matlab.ui.container.Tab
    end

    
    properties (Access = private)
        is_streaming = false; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global deviceType myoObject
            deviceType = DeviceName.myo;
            [myoObject, ~] = connectMyo("fake");
            
            global path_root;
            path_root = "/home/magody/programming/MATLAB/tesis/";
            
            global window_size stride orientation sample_time_ms
            window_size = 300;
            stride = 30;
            user_id = 1;
            user_full_dir = path_root +"Data/preprocessingTest/";
            user_folder = "user"+user_id;
            is_legacy = false;
            userData = loadUserByNameAndDir(user_folder, char(user_full_dir), is_legacy);
            rng('default');
            orientation = getOrientation(userData, user_folder);
            
            sample_time_ms = 990;
            global model context;
            addpath(genpath(path_root + "ModelingAndExperiments/RLSetup"));
            context = containers.Map();
            context('tabulation_mode') = 2;
            context('is_preprocessed') = true;
            context('noGestureDetection') = false;
            context('window_size') = window_size;
            context('stride') = stride;
            context('rewards') = struct('correct', 1, 'incorrect', -1);
            context('RepTesting') = 1;
            context('rangeDownTest') = 1;
                
            model = load(path_root + "ModelingAndExperiments/models_output/" + user_folder + ".mat");

        end

        % Button pushed function: button_play
        function togglePlay(app, event)
            global myoObject h plot_call_counter;
            if app.is_streaming
                myoObject.myoData.stopStreaming();
                stop(timerfindall);
                app.is_streaming = false;
                app.button_play.Icon = 'button_play.png';
            else
                myoObject.myoData.startStreaming();
                app.is_streaming = true;
                app.button_play.Icon = 'button_pause.png';
                
                clf();
                h = animatedline;
                
                
                plot_call_counter = 0;
                timer_plotAnimatedEMG = timer('Name', 'timer_plotAnimatedEMG', 'TimerFcn', @timerPlotAnimatedEMG, ...
                          'ExecutionMode', 'fixedRate', 'Period', 0.2);
                start(timer_plotAnimatedEMG);
                
                timer_snapshotEMG = timer('Name', 'timer_snapshotEMG', 'TimerFcn', @timerSnapshotEMG, ...
                          'ExecutionMode', 'fixedRate', 'Period', 1);
                start(timer_snapshotEMG);
            end


            app.IsstreamingLamp.Enable = app.is_streaming;
        end

        % Button pushed function: DebugButton
        function debug(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main = uifigure('Visible', 'off');
            app.main.Name = 'MATLAB App';
            app.Menu = uimenu(app.main);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.main);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.main);
            app.StreamingTab = uitab(app.TabGroup);
            app.StreamingTab.Title = 'Streaming';
            app.IsstreamingLampLabel = uilabel(app.StreamingTab);
            app.IsstreamingLampLabel.Text = 'Is streaming';
            app.IsstreamingLamp = uilamp(app.StreamingTab);
            app.IsstreamingLamp.Enable = 'off';
            app.button_play = uibutton(app.StreamingTab, 'push');
            app.button_play.ButtonPushedFcn = createCallbackFcn(app, @togglePlay, true);
            app.button_play.Icon = 'button_play.png';
            app.button_play.Text = '';
            app.DebugButton = uibutton(app.StreamingTab, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @debug, true);
            app.DebugButton.Text = 'Debug';
            app.StaticTab = uitab(app.TabGroup);
            app.StaticTab.Title = 'Static';
            % Show the figure after all components are created
            app.main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appUIMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main)
        end
    end
end